classdef gui_ow_lab2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        RankingTextAreaLabel        matlab.ui.control.Label
        RankingTextArea             matlab.ui.control.TextArea
        Run_TOPSISButton            matlab.ui.control.Button
        WprowadzwektorwagowyButton  matlab.ui.control.Button
        WprowadzograniczeniaButton  matlab.ui.control.Button
        WprowadzAButton             matlab.ui.control.Button
        WprowadzBButton             matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        DanezplikuButton            matlab.ui.control.Button
        Point_typeDropDownLabel     matlab.ui.control.Label
        Point_typeDropDown          matlab.ui.control.DropDown
        WektorwagowyTextAreaLabel   matlab.ui.control.Label
        WektorwagowyTextArea        matlab.ui.control.TextArea
        OgraniczeniaTextAreaLabel   matlab.ui.control.Label
        OgraniczeniaTextArea        matlab.ui.control.TextArea
        ATextAreaLabel              matlab.ui.control.Label
        ATextArea                   matlab.ui.control.TextArea
        BTextAreaLabel              matlab.ui.control.Label
        BTextArea                   matlab.ui.control.TextArea
        DanewejcioweTextAreaLabel   matlab.ui.control.Label
        DanewejcioweTextArea        matlab.ui.control.TextArea
        MinMaxDropDownLabel         matlab.ui.control.Label
        MinMaxDropDown              matlab.ui.control.DropDown
        Tab2                        matlab.ui.container.Tab
    end

    
    properties (Access = private)
        dane = [
  700 2 6 3;
  700 2 6 3;
  250 2 4 5;
  1200 6 9 8;
  1200 6 9 8;
  700 2 6 3;
  450 3 8 9;
  1500 2 3 10
];
        ograniczenia = [
  0 0 0 0;
  1600 7 10 20
];

wektorWag = [0.45 0.3 0.15 0.1];
        % 1 = min, -1 = max
        minMax = -1*[1 1 1 1]; 

        pointTypes = "ALL";
        %pointTypes = "NZD";

        A = 0.2; %Parametr opcjonalny, domy¶lnie A = 0.5, nie zmienia skalowania
        B = 0.8; %Parametr opcjonalny, domy¶lnie B = 1-A
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ATextArea.Value = mat2str(app.A);
            app.BTextArea.Value = mat2str(app.B);
            app.OgraniczeniaTextArea.Value = mat2str(app.ograniczenia);
            app.WektorwagowyTextArea.Value = mat2str(app.wektorWag);
            app.DanewejcioweTextArea.Value = mat2str(app.dane);
        end

        % Button pushed function: Run_TOPSISButton
        function Run_TOPSISButtonPushed(app, event)

            if nargin < 6
                app.A = 0.5;
            end
   
   if nargin < 7
      app.B = 1 - app.A; 
   end

  % okreslenie wielkosci problemu
  [liczbaAlternatyw, iloscKryteriow] = size(app.dane);
  
  % sprawdzenie punktow alternatyw z ograniczeniami
  alternatywyOK = zeros(liczbaAlternatyw, 1);
  for i =1:liczbaAlternatyw
    for j =1:iloscKryteriow
      if app.dane(i,j) >= app.ograniczenia(1,j) && app.dane(i,j) <= app.ograniczenia(2,j)
        alternatywyOK(i,1) = i;
      else
        alternatywyOK (i,1) = 0;
        %break;
      end
    end
  end
  
  
  liczbaAlternatywTemp = 0;
  for i =1:liczbaAlternatyw
    if alternatywyOK(i,1) == 0
    else
      liczbaAlternatywTemp = liczbaAlternatywTemp + 1;
    end
  end

  % uzupelnienie macierzy decyzyjnej
  id = 1;
  macierz_decyzyjna = zeros(liczbaAlternatywTemp,iloscKryteriow); 
  
  for i =1:liczbaAlternatyw  
    if alternatywyOK(i,1) ~= 0
      for j =1:iloscKryteriow
        macierz_decyzyjna(id,j) = app.dane(i,j);
      end 
      id= id+1;
    end
  end
  
liczbaAlternatyw = liczbaAlternatywTemp;


% proces skalowania
macierz_skalowana = zeros(liczbaAlternatyw,iloscKryteriow); 
for i =1:liczbaAlternatyw
  for j =1:iloscKryteriow
    macierz_skalowana(i,j) = macierz_decyzyjna(i,j)/sqrt(sum((macierz_decyzyjna(:,j).^2)));
    % Zmiana na max
    if( app.minMax(j) == -1 ) 
      macierz_skalowana(i,j) = abs( 1 - macierz_skalowana(i,j) );
    end
    
    macierz_skalowana(i,j) = macierz_skalowana(i,j) * app.wektorWag(j);
    
  end
end


%Okreslenie czy wektor antyidealny jest brany ze wszsytkich punktow czy z
%niezdominowanych
if app.pointTypes == "ALL"
    points = macierz_skalowana;
elseif app.pointTypes == "NZD"
    niezdominowane = find_niezdominowane(macierz_skalowana')';
    points = niezdominowane;
else
    error('Nieprawidowa wartosc pointTypes');
end

wektorIdealny = zeros( length(points(1,:)) , 1);
wektorAntyIdealny = zeros( length(points(1,:)) , 1);

for i = 1:length(wektorIdealny)
    wektorIdealny(i) = min( points(:,i));
    wektorAntyIdealny(i) = max( points(:,i));
end


% wyznaczenie odleglosci w przestrzeni euklidesowej
odleglosci = zeros(liczbaAlternatyw,2);
for i =1:liczbaAlternatyw
  sumaIdealny  = 0;
  sumaAntyIdealny = 0;
  for j =1:iloscKryteriow-2
    sumaIdealny = sumaIdealny+(macierz_skalowana(i,j) - wektorIdealny(j)).^2;
    sumaAntyIdealny = sumaAntyIdealny+(macierz_skalowana(i,j) - wektorAntyIdealny(j)).^2; 
  end
  
  % skalowanie poprzez A i B
  
  odleglosci(i,1) = app.A * sqrt(sumaIdealny);
  odleglosci(i,2) = app.B * sqrt(sumaAntyIdealny);
end 

% obliczenie wartosci rankingu
ranking_wart = zeros(liczbaAlternatyw,1);
for i =1:liczbaAlternatyw
    if odleglosci(i,1)+odleglosci(i,2) == 0
        ranking_wart(i) = 0;
    else
      ranking_wart(i) = odleglosci(i,2)/(odleglosci(i,1)+odleglosci(i,2));
    end
end


% przypisanie "miejsc" rankingu
ranking = zeros(liczbaAlternatyw,1);
last_min = Inf;
last_place = 1;
for i = 1:length(ranking_wart)
    [v,idx] = min(ranking_wart);
    
    if abs(v - last_min) < 1e-4
        ranking(idx) = last_place;
    else
        ranking(idx) = i;
        last_place = i;
    end
    
    ranking_wart(idx) = Inf;
    
    last_min = v;
    app.RankingTextArea.Value = mat2str(ranking);
end

hold on;
h1 = plot3(app.UIAxes,macierz_decyzyjna(:,1),macierz_decyzyjna(:,2),macierz_decyzyjna(:,3),'m*'); 
for i = 1:liczbaAlternatyw
  label = sprintf("  Obiekt %d, Scoring: %.4f", i, ranking(i));
  text(app.UIAxes,macierz_decyzyjna(i,1),macierz_decyzyjna(i,2),macierz_decyzyjna(i,3),label,'HorizontalAlignment','left');
end
h2 = plot3(app.UIAxes,daneK(:,1),daneK(:,2),daneK(:,3),'ko');
grid on;
legend(app.UIAxes,[h1 h2], 'Macierz decyzyjna', 'Dane');
hold off;
        end

        % Button pushed function: WprowadzwektorwagowyButton
        function WprowadzwektorwagowyButtonPushed(app, event)
            prompt = {'Enter rows', 'Enter columns'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'0', '0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            for row = 1:str2double(cell2mat(answer(1)))
                for col = 1:str2double(cell2mat(answer(2)))
                    prompt = {['p(',num2str(row),',',num2str(col),')']};
                    dlgtitle = 'Input';
                    dims = [1 35];
                    definput = {'0'};
                    answer1 = inputdlg(prompt,dlgtitle,dims,definput);
                    wektor_wag(row,col) = str2double(cell2mat(answer1(1)));
                end
            end
            app.wektorWag = wektor_wag;
            app.WektorwagowyTextArea.Value = mat2str(app.wektorWag);
        end

        % Button pushed function: WprowadzograniczeniaButton
        function WprowadzograniczeniaButtonPushed(app, event)
            prompt = {'Enter rows', 'Enter columns'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'0', '0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            for row = 1:str2double(cell2mat(answer(1)))
                for col = 1:str2double(cell2mat(answer(2)))
                    prompt = {['p(',num2str(row),',',num2str(col),')']};
                    dlgtitle = 'Input';
                    dims = [1 35];
                    definput = {'0'};
                    answer1 = inputdlg(prompt,dlgtitle,dims,definput);
                    ograniczenia_button(row,col) = str2double(cell2mat(answer1(1)));
                end
            end
            app.ograniczenia = ograniczenia_button;
            app.OgraniczeniaTextArea.Value = mat2str(app.ograniczenia);
        end

        % Value changed function: Point_typeDropDown
        function Point_typeDropDownValueChanged(app, event)
            point_type_button = app.Point_typeDropDown.Value;
            app.pointTypes = point_type_button;
        end

        % Button pushed function: WprowadzAButton
        function WprowadzAButtonPushed(app, event)
            prompt = {'Enter value of A'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.A = str2double(cell2mat(answer(1)));
            app.ATextArea.Value = mat2str(app.A);
        end

        % Button pushed function: WprowadzBButton
        function WprowadzBButtonPushed(app, event)
            prompt = {'Enter value of B'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.B = str2double(cell2mat(answer(1)));
            app.BTextArea.Value = mat2str(app.B);
        end

        % Button pushed function: DanezplikuButton
        function DanezplikuButtonPushed(app, event)
            [file,folder] = uigetfile({'*'});
            filename = fullfile(folder,file);
            dataTable = readtable(filename);
            dataButton = table2array(dataTable);
            app.DanewejcioweTextArea.Value = mat2str(dataButton);
            app.dane = dataButton;
        end

        % Value changed function: MinMaxDropDown
        function MinMaxDropDownValueChanged(app, event)
            value = app.MinMaxDropDown.Value;
            if value == "MIN"
                tmp = ones(size(app.wektorWag));
                minMaxButton = tmp;
                app.minMax = minMaxButton;
            end
            if value == "MAX"
                tmp = ones(size(app.wektorWag));
                minMaxButton = -1*tmp;
                app.minMax = minMaxButton;
            end
        end

        % Callback function
        function WewntrznaniesprzecznoMenuSelected(app, event)
            
        end

        % Callback function
        function WewntrznaiwzajemnaniesprzecznoMenuSelected(app, event)
            app2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.RankingTextAreaLabel = uilabel(app.Tab);
            app.RankingTextAreaLabel.Text = 'Ranking';
            app.RankingTextArea = uitextarea(app.Tab);
            app.Run_TOPSISButton = uibutton(app.Tab, 'push');
            app.Run_TOPSISButton.ButtonPushedFcn = createCallbackFcn(app, @Run_TOPSISButtonPushed, true);
            app.Run_TOPSISButton.Text = 'Run_TOPSIS';
            app.WprowadzwektorwagowyButton = uibutton(app.Tab, 'push');
            app.WprowadzwektorwagowyButton.ButtonPushedFcn = createCallbackFcn(app, @WprowadzwektorwagowyButtonPushed, true);
            app.WprowadzwektorwagowyButton.Text = 'Wprowadz wektor wagowy';
            app.WprowadzograniczeniaButton = uibutton(app.Tab, 'push');
            app.WprowadzograniczeniaButton.ButtonPushedFcn = createCallbackFcn(app, @WprowadzograniczeniaButtonPushed, true);
            app.WprowadzograniczeniaButton.Text = 'Wprowadz ograniczenia';
            app.WprowadzAButton = uibutton(app.Tab, 'push');
            app.WprowadzAButton.ButtonPushedFcn = createCallbackFcn(app, @WprowadzAButtonPushed, true);
            app.WprowadzAButton.Text = 'Wprowadz A';
            app.WprowadzBButton = uibutton(app.Tab, 'push');
            app.WprowadzBButton.ButtonPushedFcn = createCallbackFcn(app, @WprowadzBButtonPushed, true);
            app.WprowadzBButton.Text = 'Wprowadz B';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DanezplikuButton = uibutton(app.Tab, 'push');
            app.DanezplikuButton.ButtonPushedFcn = createCallbackFcn(app, @DanezplikuButtonPushed, true);
            app.DanezplikuButton.Text = 'Dane z pliku';
            app.Point_typeDropDownLabel = uilabel(app.Tab);
            app.Point_typeDropDownLabel.Text = 'Point_type';
            app.Point_typeDropDown = uidropdown(app.Tab);
            app.Point_typeDropDown.Items = {'ALL', 'NZD'};
            app.Point_typeDropDown.ValueChangedFcn = createCallbackFcn(app, @Point_typeDropDownValueChanged, true);
            app.Point_typeDropDown.Value = 'ALL';
            app.WektorwagowyTextAreaLabel = uilabel(app.Tab);
            app.WektorwagowyTextAreaLabel.Text = 'Wektor wagowy';
            app.WektorwagowyTextArea = uitextarea(app.Tab);
            app.OgraniczeniaTextAreaLabel = uilabel(app.Tab);
            app.OgraniczeniaTextAreaLabel.Text = 'Ograniczenia';
            app.OgraniczeniaTextArea = uitextarea(app.Tab);
            app.ATextAreaLabel = uilabel(app.Tab);
            app.ATextAreaLabel.Text = 'A';
            app.ATextArea = uitextarea(app.Tab);
            app.BTextAreaLabel = uilabel(app.Tab);
            app.BTextAreaLabel.Text = 'B';
            app.BTextArea = uitextarea(app.Tab);
            app.DanewejcioweTextAreaLabel = uilabel(app.Tab);
            app.DanewejcioweTextAreaLabel.Text = 'Dane wejściowe';
            app.DanewejcioweTextArea = uitextarea(app.Tab);
            app.MinMaxDropDownLabel = uilabel(app.Tab);
            app.MinMaxDropDownLabel.Text = 'MinMax';
            app.MinMaxDropDown = uidropdown(app.Tab);
            app.MinMaxDropDown.Items = {'MAX', 'MIN'};
            app.MinMaxDropDown.ValueChangedFcn = createCallbackFcn(app, @MinMaxDropDownValueChanged, true);
            app.MinMaxDropDown.Value = 'MAX';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_ow_lab2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end